classdef Queen8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Column1_1                     matlab.ui.control.Label
        Column1_2                     matlab.ui.control.Label
        Column1_3                     matlab.ui.control.Label
        Column1_4                     matlab.ui.control.Label
        Column1_5                     matlab.ui.control.Label
        Column1_6                     matlab.ui.control.Label
        Column1_7                     matlab.ui.control.Label
        Column1_8                     matlab.ui.control.Label
        Column2_1                     matlab.ui.control.Label
        Column2_2                     matlab.ui.control.Label
        Column2_3                     matlab.ui.control.Label
        Column2_4                     matlab.ui.control.Label
        Column2_5                     matlab.ui.control.Label
        Column2_6                     matlab.ui.control.Label
        Column2_7                     matlab.ui.control.Label
        Column2_8                     matlab.ui.control.Label
        Column3_1                     matlab.ui.control.Label
        Column3_2                     matlab.ui.control.Label
        Column3_3                     matlab.ui.control.Label
        Column3_4                     matlab.ui.control.Label
        Column3_5                     matlab.ui.control.Label
        Column3_6                     matlab.ui.control.Label
        Column3_7                     matlab.ui.control.Label
        Column3_8                     matlab.ui.control.Label
        Column4_1                     matlab.ui.control.Label
        Column4_2                     matlab.ui.control.Label
        Column4_3                     matlab.ui.control.Label
        Column4_4                     matlab.ui.control.Label
        Column4_5                     matlab.ui.control.Label
        Column4_6                     matlab.ui.control.Label
        Column4_7                     matlab.ui.control.Label
        Column4_8                     matlab.ui.control.Label
        Column5_1                     matlab.ui.control.Label
        Column5_2                     matlab.ui.control.Label
        Column5_3                     matlab.ui.control.Label
        Column5_4                     matlab.ui.control.Label
        Column5_5                     matlab.ui.control.Label
        Column5_6                     matlab.ui.control.Label
        Column5_7                     matlab.ui.control.Label
        Column5_8                     matlab.ui.control.Label
        Column6_1                     matlab.ui.control.Label
        Column6_2                     matlab.ui.control.Label
        Column6_3                     matlab.ui.control.Label
        Column6_4                     matlab.ui.control.Label
        Column6_5                     matlab.ui.control.Label
        Column6_6                     matlab.ui.control.Label
        Column6_7                     matlab.ui.control.Label
        Column6_8                     matlab.ui.control.Label
        Column7_1                     matlab.ui.control.Label
        Column7_2                     matlab.ui.control.Label
        Column7_3                     matlab.ui.control.Label
        Column7_4                     matlab.ui.control.Label
        Column7_5                     matlab.ui.control.Label
        Column7_6                     matlab.ui.control.Label
        Column7_7                     matlab.ui.control.Label
        Column7_8                     matlab.ui.control.Label
        Column8_1                     matlab.ui.control.Label
        Column8_2                     matlab.ui.control.Label
        Column8_3                     matlab.ui.control.Label
        Column8_4                     matlab.ui.control.Label
        Column8_5                     matlab.ui.control.Label
        Column8_6                     matlab.ui.control.Label
        Column8_7                     matlab.ui.control.Label
        Column8_8                     matlab.ui.control.Label
        SolveButton                   matlab.ui.control.Button
        QueenProblemLabel             matlab.ui.control.Label
        ProgrammedbyAliTabaraeiLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            for i=1:8
                for j=1:8
                    if mod(i+j, 2) == 0
                        s = ['app.Column', num2str(i), '_', num2str(j) , '.BackgroundColor' ];
                        eval([s ' =  [0.94, 0.94, 0.94]']);
                    else
                        s = ['app.Column', num2str(i), '_', num2str(j) , '.BackgroundColor' ];
                        eval([s ' =  [1, 1, 1]']);
                    end
                end
            end
            
            Solution = Queen();
            for i=1:length(Solution)
                s = ['app.Column', num2str(i), '_', num2str(Solution(i)), '.BackgroundColor' ];
                eval([s ' =  [0.5, 0.5, 0.5]']);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Column1_1 = uilabel(app.UIFigure);
            app.Column1_1.Text = '';
            app.Column1_2 = uilabel(app.UIFigure);
            app.Column1_2.Text = '';
            app.Column1_3 = uilabel(app.UIFigure);
            app.Column1_3.Text = '';
            app.Column1_4 = uilabel(app.UIFigure);
            app.Column1_4.Text = '';
            app.Column1_5 = uilabel(app.UIFigure);
            app.Column1_5.Text = '';
            app.Column1_6 = uilabel(app.UIFigure);
            app.Column1_6.Text = '';
            app.Column1_7 = uilabel(app.UIFigure);
            app.Column1_7.Text = '';
            app.Column1_8 = uilabel(app.UIFigure);
            app.Column1_8.Text = '';
            app.Column2_1 = uilabel(app.UIFigure);
            app.Column2_1.Text = '';
            app.Column2_2 = uilabel(app.UIFigure);
            app.Column2_2.Text = '';
            app.Column2_3 = uilabel(app.UIFigure);
            app.Column2_3.Text = '';
            app.Column2_4 = uilabel(app.UIFigure);
            app.Column2_4.Text = '';
            app.Column2_5 = uilabel(app.UIFigure);
            app.Column2_5.Text = '';
            app.Column2_6 = uilabel(app.UIFigure);
            app.Column2_6.Text = '';
            app.Column2_7 = uilabel(app.UIFigure);
            app.Column2_7.Text = '';
            app.Column2_8 = uilabel(app.UIFigure);
            app.Column2_8.Text = '';
            app.Column3_1 = uilabel(app.UIFigure);
            app.Column3_1.Text = '';
            app.Column3_2 = uilabel(app.UIFigure);
            app.Column3_2.Text = '';
            app.Column3_3 = uilabel(app.UIFigure);
            app.Column3_3.Text = '';
            app.Column3_4 = uilabel(app.UIFigure);
            app.Column3_4.Text = '';
            app.Column3_5 = uilabel(app.UIFigure);
            app.Column3_5.Text = '';
            app.Column3_6 = uilabel(app.UIFigure);
            app.Column3_6.Text = '';
            app.Column3_7 = uilabel(app.UIFigure);
            app.Column3_7.Text = '';
            app.Column3_8 = uilabel(app.UIFigure);
            app.Column3_8.Text = '';
            app.Column4_1 = uilabel(app.UIFigure);
            app.Column4_1.Text = '';
            app.Column4_2 = uilabel(app.UIFigure);
            app.Column4_2.Text = '';
            app.Column4_3 = uilabel(app.UIFigure);
            app.Column4_3.Text = '';
            app.Column4_4 = uilabel(app.UIFigure);
            app.Column4_4.Text = '';
            app.Column4_5 = uilabel(app.UIFigure);
            app.Column4_5.Text = '';
            app.Column4_6 = uilabel(app.UIFigure);
            app.Column4_6.Text = '';
            app.Column4_7 = uilabel(app.UIFigure);
            app.Column4_7.Text = '';
            app.Column4_8 = uilabel(app.UIFigure);
            app.Column4_8.Text = '';
            app.Column5_1 = uilabel(app.UIFigure);
            app.Column5_1.Text = '';
            app.Column5_2 = uilabel(app.UIFigure);
            app.Column5_2.Text = '';
            app.Column5_3 = uilabel(app.UIFigure);
            app.Column5_3.Text = '';
            app.Column5_4 = uilabel(app.UIFigure);
            app.Column5_4.Text = '';
            app.Column5_5 = uilabel(app.UIFigure);
            app.Column5_5.Text = '';
            app.Column5_6 = uilabel(app.UIFigure);
            app.Column5_6.Text = '';
            app.Column5_7 = uilabel(app.UIFigure);
            app.Column5_7.Text = '';
            app.Column5_8 = uilabel(app.UIFigure);
            app.Column5_8.Text = '';
            app.Column6_1 = uilabel(app.UIFigure);
            app.Column6_1.Text = '';
            app.Column6_2 = uilabel(app.UIFigure);
            app.Column6_2.Text = '';
            app.Column6_3 = uilabel(app.UIFigure);
            app.Column6_3.Text = '';
            app.Column6_4 = uilabel(app.UIFigure);
            app.Column6_4.Text = '';
            app.Column6_5 = uilabel(app.UIFigure);
            app.Column6_5.Text = '';
            app.Column6_6 = uilabel(app.UIFigure);
            app.Column6_6.Text = '';
            app.Column6_7 = uilabel(app.UIFigure);
            app.Column6_7.Text = '';
            app.Column6_8 = uilabel(app.UIFigure);
            app.Column6_8.Text = '';
            app.Column7_1 = uilabel(app.UIFigure);
            app.Column7_1.Text = '';
            app.Column7_2 = uilabel(app.UIFigure);
            app.Column7_2.Text = '';
            app.Column7_3 = uilabel(app.UIFigure);
            app.Column7_3.Text = '';
            app.Column7_4 = uilabel(app.UIFigure);
            app.Column7_4.Text = '';
            app.Column7_5 = uilabel(app.UIFigure);
            app.Column7_5.Text = '';
            app.Column7_6 = uilabel(app.UIFigure);
            app.Column7_6.Text = '';
            app.Column7_7 = uilabel(app.UIFigure);
            app.Column7_7.Text = '';
            app.Column7_8 = uilabel(app.UIFigure);
            app.Column7_8.Text = '';
            app.Column8_1 = uilabel(app.UIFigure);
            app.Column8_1.Text = '';
            app.Column8_2 = uilabel(app.UIFigure);
            app.Column8_2.Text = '';
            app.Column8_3 = uilabel(app.UIFigure);
            app.Column8_3.Text = '';
            app.Column8_4 = uilabel(app.UIFigure);
            app.Column8_4.Text = '';
            app.Column8_5 = uilabel(app.UIFigure);
            app.Column8_5.Text = '';
            app.Column8_6 = uilabel(app.UIFigure);
            app.Column8_6.Text = '';
            app.Column8_7 = uilabel(app.UIFigure);
            app.Column8_7.Text = '';
            app.Column8_8 = uilabel(app.UIFigure);
            app.Column8_8.Text = '';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.QueenProblemLabel = uilabel(app.UIFigure);
            app.QueenProblemLabel.Text = '8-Queen Problem';
            app.ProgrammedbyAliTabaraeiLabel = uilabel(app.UIFigure);
            app.ProgrammedbyAliTabaraeiLabel.Text = 'Programmed by Ali Tabaraei';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Queen8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end